classdef NumericalMethodsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FINALXEditField               matlab.ui.control.NumericEditField
        FINALXEditFieldLabel          matlab.ui.control.Label
        hEditField                    matlab.ui.control.NumericEditField
        hEditFieldLabel               matlab.ui.control.Label
        INITIALYEditField             matlab.ui.control.NumericEditField
        INITIALYEditFieldLabel        matlab.ui.control.Label
        INITIALXEditField             matlab.ui.control.NumericEditField
        INITIALXEditField_2Label      matlab.ui.control.Label
        INITIALX0EditField            matlab.ui.control.NumericEditField
        INITIALGUESSX0EditFieldLabel  matlab.ui.control.Label
        UPPERBOUNDEditField           matlab.ui.control.NumericEditField
        UPPERBOUNDEditFieldLabel      matlab.ui.control.Label
        RESULTLabel                   matlab.ui.control.Label
        CALCULATEButton               matlab.ui.control.Button
        TOLERANCEEditField            matlab.ui.control.NumericEditField
        TOLERANCEEditFieldLabel       matlab.ui.control.Label
        LOWERBOUNDEditField           matlab.ui.control.NumericEditField
        LOWERBOUNDEditFieldLabel      matlab.ui.control.Label
        FUNCTIONEditField             matlab.ui.control.EditField
        FUNCTIONLabel                 matlab.ui.control.Label
        METHODSDropDown               matlab.ui.control.DropDown
        METHODSLabel                  matlab.ui.control.Label
        NUMERICALMETHODSSOLVERLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            method = app.METHODSDropDown.Value;
            if strcmp(method, 'BISECTION')
                a = app.LOWERBOUNDEditField.Value;
                b = app.UPPERBOUNDEditField.Value;
                tol=app.TOLERANCEEditField.Value;
                functiontext = app.FUNCTIONEditField.Value;

                f = str2func(['@(x)' functiontext]);

                if f(a)*f(b)>0
                    uialert(app.UIFigure, 'Çhoose an interval with opposite signs.', 'invalid interval');
                return
                end
                while abs(b-a)>tol
                    c = (a+b)/2;
                    if f(a)*f(b)<0
                        b = c;
                    else
                        a = c;
                    end
                end
                root = (a+b)/2;
                app.RESULTLabel.Text = ['Root = ' num2str(root)];
           
            elseif strcmp(method, 'NEWTON-RAPHSON')
                x = app.INITIALX0EditField.Value;
                tol = app.TOLERANCEEditField.Value;
                functionText = app.FUNCTIONEditField.Value;

                f = str2func(['@(x)' functionText]);
                df = @(x) (f(x+1e-6)-f(x-1e-6))/(2e-6);

                while abs(f(x))>tol
                    if abs(df(x))<1e-12
                        uialert(app.UIFigure, 'Derivative is zero, choose another initial value.', 'error');
                        return
                    end
                    x = x-f(x)/df(x);
                end
                app.RESULTLabel.Text = ['Root = ' num2str(x)];

            elseif strcmp(method, 'SECANT')
                x0 = app.LOWERBOUNDEditField.Value;
                x1 = app.UPPERBOUNDEditField.Value;
                tol = app.TOLERANCEEditField.Value;
                functionText = app.FUNCTIONEditField.Value;

                f = str2func(['@(x)' functionText]);
                
                for k = 1:1000
                    f0 = f(0);
                    f1 = f(1);

                    if abs(f1)<tol
                        break
                    end
                    if abs(f1-f0)<1e-12
                        uialert(app.UIFigure, 'Division by zero. Try different initial guess.', 'Calculation error')
                        return
                    end
                    x2 = x1-f1*(x1-x0)/(f1-f0);
                    if abs(x2-x1)<tol
                        x1 = x2;
                        break
                    end
                    x0 = x1;
                    x1 = x2;
                end
                app.RESULTLabel.Text = ['Root = ' num2str(x1)];

            elseif strcmp(method, 'RK4')
                x = app.INITIALX0EditField.Value;
                y = app.INITIALYEditField.Value;
                h = app.hEditField.Value;
                xf = app.FINALXEditField.Value;
                tol = app.TOLERANCEEditField.Value;
                
                functionText = app.FUNCTIONEditField.Value;
                f = str2func(['@(x,y)' functionText]);
                
                if h <= 0 || xf <= x
                    uialert(app.UIFigure, 'Step size must be positive and Final X must exceed Initial X.', 'Input Error');
                    return
                end
                results = sprintf('x = %.4f, y = %.6f\n', x, y);

                while x < xf
                    hstep = min(h, xf - x);
                    k1 = hstep * f(x, y);
                    k2 = hstep * f(x + hstep/2, y + k1/2);
                    k3 = hstep * f(x + hstep/2, y + k2/2);
                    k4 = hstep * f(x + hstep, y + k3);
                    y = y + (k1 + 2*k2 + 2*k3 + k4)/6;
                    x = x + hstep;
                    results = [results sprintf('x = %.4f, y = %.6f\n', x, y)];
                end
                app.RESULTLabel.Text = sprintf('RK4 Final Result:\nFinal x = %.3f\nFinal y = %.3f', x, y);
            end
        end

        % Value changed function: METHODSDropDown
        function METHODSDropDownValueChanged(app, event)
            method = app.METHODSDropDown.Value;
            if strcmp(method, 'BISECTION')
                app.LOWERBOUNDEditField.Visible = 'on';
                app.UPPERBOUNDEditField.Visible = 'on';
                app.INITIALX0EditField.Visible = 'off';
                app.INITIALXEditField.Visible = 'off';
                app.INITIALYEditField.Visible = 'off';
                app.hEditField.Visible = "off";
                APP.FINALXEditField.Visible = 'off';

            elseif strcmp(method, 'NEWTON-RAPHSON')
                app.LOWERBOUNDEditField.Visible = 'off';
                app.UPPERBOUNDEditField.Visible = 'off';
                app.INITIALX0EditField.Visible = 'on';
                app.INITIALXEditField.Visible = 'off';
                app.INITIALYEditField.Visible = 'off';
                app.hEditField.Visible = "off";
                APP.FINALXEditField.Visible = 'off';

            elseif strcmp(method, 'SECANT')
                app.LOWERBOUNDEditField.Visible = 'on';
                app.UPPERBOUNDEditField.Visible = 'on';
                app.INITIALX0EditField.Visible = 'off';
                app.INITIALXEditField.Visible = 'off';
                app.INITIALYEditField.Visible = 'off';
                app.hEditField.Visible = "off";
                APP.FINALXEditField.Visible = 'off';

            elseif strcmp(method, 'RK4')
                app.LOWERBOUNDEditField.Visible = 'off';
                app.UPPERBOUNDEditField.Visible = 'off';
                app.INITIALX0EditField.Visible = 'off';
                app.INITIALXEditField.Visible = 'on';
                app.INITIALYEditField.Visible = 'on';
                app.hEditField.Visible = "on";
                APP.FINALXEditField.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NUMERICALMETHODSSOLVERLabel = uilabel(app.UIFigure);
            app.NUMERICALMETHODSSOLVERLabel.Text = 'NUMERICAL METHODS SOLVER';
            app.METHODSLabel = uilabel(app.UIFigure);
            app.METHODSLabel.Text = 'METHODS : ';
            app.METHODSDropDown = uidropdown(app.UIFigure);
            app.METHODSDropDown.Items = {'BISECTION', 'NEWTON-RAPHSON', 'SECANT', 'RK4'};
            app.METHODSDropDown.ValueChangedFcn = createCallbackFcn(app, @METHODSDropDownValueChanged, true);
            app.METHODSDropDown.Value = 'BISECTION';
            app.FUNCTIONLabel = uilabel(app.UIFigure);
            app.FUNCTIONLabel.Text = 'FUNCTION :';
            app.FUNCTIONEditField = uieditfield(app.UIFigure, 'text');
            app.FUNCTIONEditField.Value = 'x^3+7*x^2+5*x-4';
            app.LOWERBOUNDEditFieldLabel = uilabel(app.UIFigure);
            app.LOWERBOUNDEditFieldLabel.Text = 'LOWER BOUND : ';
            app.LOWERBOUNDEditField = uieditfield(app.UIFigure, 'numeric');
            app.TOLERANCEEditFieldLabel = uilabel(app.UIFigure);
            app.TOLERANCEEditFieldLabel.Text = 'TOLERANCE : ';
            app.TOLERANCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.TOLERANCEEditField.Value = 1e-05;
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.RESULTLabel = uilabel(app.UIFigure);
            app.RESULTLabel.Text = 'RESULT ';
            app.UPPERBOUNDEditFieldLabel = uilabel(app.UIFigure);
            app.UPPERBOUNDEditFieldLabel.Text = 'UPPER BOUND';
            app.UPPERBOUNDEditField = uieditfield(app.UIFigure, 'numeric');
            app.UPPERBOUNDEditField.Value = 1;
            app.INITIALGUESSX0EditFieldLabel = uilabel(app.UIFigure);
            app.INITIALGUESSX0EditFieldLabel.Text = 'INITIAL GUESS (X0) : ';
            app.INITIALX0EditField = uieditfield(app.UIFigure, 'numeric');
            app.INITIALXEditField_2Label = uilabel(app.UIFigure);
            app.INITIALXEditField_2Label.Text = 'INITIAL X : ';
            app.INITIALXEditField = uieditfield(app.UIFigure, 'numeric');
            app.INITIALXEditField.Value = 1;
            app.INITIALYEditFieldLabel = uilabel(app.UIFigure);
            app.INITIALYEditFieldLabel.Text = 'INITIAL Y :  ';
            app.INITIALYEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditField.Value = 2;
            app.FINALXEditFieldLabel = uilabel(app.UIFigure);
            app.FINALXEditFieldLabel.Text = 'FINAL X : ';
            app.FINALXEditField = uieditfield(app.UIFigure, 'numeric');
            app.FINALXEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NumericalMethodsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end